classdef musica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SelectsongButton      matlab.ui.control.Button
        EcoSwitchLabel        matlab.ui.control.Label
        EcoSwitch             matlab.ui.control.ToggleSwitch
        DropDownLabel         matlab.ui.control.Label
        DropDown              matlab.ui.control.DropDown
        Switch2Label          matlab.ui.control.Label
        Switch2               matlab.ui.control.ToggleSwitch
        Switch3Label          matlab.ui.control.Label
        Switch3               matlab.ui.control.ToggleSwitch
        Switch4Label          matlab.ui.control.Label
        Switch4               matlab.ui.control.ToggleSwitch
        Switch5Label          matlab.ui.control.Label
        Switch5               matlab.ui.control.ToggleSwitch
        EcoSwitch_2Label      matlab.ui.control.Label
        EcoSwitch_2           matlab.ui.control.ToggleSwitch
        Switch7Label          matlab.ui.control.Label
        Switch7               matlab.ui.control.ToggleSwitch
        Switch8Label          matlab.ui.control.Label
        Switch8               matlab.ui.control.ToggleSwitch
        Switch9Label          matlab.ui.control.Label
        Switch9               matlab.ui.control.ToggleSwitch
        Switch10Label         matlab.ui.control.Label
        Switch10              matlab.ui.control.ToggleSwitch
        Efectoscanal1Label    matlab.ui.control.Label
        Efectoscanal2Label    matlab.ui.control.Label
        EfectosdeSonidoLabel  matlab.ui.control.Label
        StartButton           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectsongButton
        function SelectsongButtonPushed(app, event)
            [file,path]=uigetfile('.wav');
            if file==0
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
                [x,Fs]=audioread(file);
                %ch1_x=x(:,1);
                ch2_x=x(:,2);
                assignin("base","ch1_x",x);
                assignin("base","ch2_x",ch2_x);
                assignin("base","Fs",Fs);
            end
        end

        % Value changed function: EcoSwitch
        function EcoSwitchValueChanged(app, event)
            value = app.EcoSwitch.Value;
            ch1_x = evalin("base","ch1_x");
            Fs=evalin("base","Fs");
            alpha=0.5;
            D=0.25*Fs;
            y2 = zeros(size(ch1_x));
            xD2=0;
            yD2=0;
            N = length(ch1_x);
            assignin("base","ch1_x",ch1_x);
            if strcmp(value,'On')
                for n= 1:N
                    if n<D+1
                        y2(n)=ch1_x(n);
                    end
                    if n>=D+1
                        y2(n)=ch1_x(n)+(1-alpha)*ch1_x(n-D)+alpha*y2(n-D);
                    end
                end
                assignin("base","ch1_x",y2);
            end
        end

        % Value changed function: EcoSwitch_2
        function EcoSwitch_2ValueChanged(app, event)
            value = app.EcoSwitch_2.Value;
            ch2_x = evalin("base","ch2_x");
            alpha=0.5;
            D=0.25*Fs;
            y2 = zeros(size(ch2_x));
            xD2=0;
            yD2=0;
            N = length(ch2_x);
            if strcmp(value,'On')
                for n= 1:N
                    if n<D+1
                        y2(n)=x(n);
                    end
                    if n>=D+1
                        y2(n)=ch2_x(n)+(1-alpha)*ch_x(n-D)+alpha*y2(n-D);
                    end
                end
            else
                clear soundsc
            end
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            value = app.Switch2.Value;
            ch1_x = evalin("base","ch1_x");
            N=length(ch1_x);
            gain=1;
            mix=5;
            %th=1/3;
            if strcmp(value,'On')
                q=ch1_x*gain;
                z=sign(q).*(1-exp(-abs(q)));
                y=mix*z+(1-mix)*ch1_x;
%                 for i=1:1:N
%                     if abs(ch1_x(i))<th
%                         y(i)=2*ch1_x(i);
%                     end
%                     if abs(ch1_x(i))>=th
%                         if ch1_x(i)>0
%                             y(i)=(3-(2-ch1_x(i)*3).^2)/3;
%                         end
%                         if ch1_x(i)<0
%                             y(i)=-(3-(2-abs(ch1_x(i))*3).^2)/3;
%                         end
%                     end
%                     if abs(ch1_x(i))>2*th
%                         if ch1_x(i)>0
%                             y(i)=1;
%                         end
%                         if ch1_x(i)<0;
%                             y(i)=-1;
%                         end
%                     end
%                 end
                assignin("base","ch1_x",y);
            end
            if strcmp(value,'Off')
               assignin("base","ch1_x",ch1_x);
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            ch1_x = evalin("base","ch1_x");
            soundsc(ch1_x,44100);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelectsongButton = uibutton(app.UIFigure, 'push');
            app.SelectsongButton.ButtonPushedFcn = createCallbackFcn(app, @SelectsongButtonPushed, true);
            app.SelectsongButton.Text = 'Select song';
            app.EcoSwitchLabel = uilabel(app.UIFigure);
            app.EcoSwitchLabel.Text = 'Eco';
            app.EcoSwitch = uiswitch(app.UIFigure, 'toggle');
            app.EcoSwitch.ValueChangedFcn = createCallbackFcn(app, @EcoSwitchValueChanged, true);
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.Switch2Label = uilabel(app.UIFigure);
            app.Switch2Label.Text = 'Switch2';
            app.Switch2 = uiswitch(app.UIFigure, 'toggle');
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.Switch3Label = uilabel(app.UIFigure);
            app.Switch3Label.Text = 'Switch3';
            app.Switch3 = uiswitch(app.UIFigure, 'toggle');
            app.Switch4Label = uilabel(app.UIFigure);
            app.Switch4Label.Text = 'Switch4';
            app.Switch4 = uiswitch(app.UIFigure, 'toggle');
            app.Switch5Label = uilabel(app.UIFigure);
            app.Switch5Label.Text = 'Switch5';
            app.Switch5 = uiswitch(app.UIFigure, 'toggle');
            app.EcoSwitch_2Label = uilabel(app.UIFigure);
            app.EcoSwitch_2Label.Text = {'Eco'; ''};
            app.EcoSwitch_2 = uiswitch(app.UIFigure, 'toggle');
            app.EcoSwitch_2.ValueChangedFcn = createCallbackFcn(app, @EcoSwitch_2ValueChanged, true);
            app.Switch7Label = uilabel(app.UIFigure);
            app.Switch7Label.Text = 'Switch7';
            app.Switch7 = uiswitch(app.UIFigure, 'toggle');
            app.Switch8Label = uilabel(app.UIFigure);
            app.Switch8Label.Text = 'Switch8';
            app.Switch8 = uiswitch(app.UIFigure, 'toggle');
            app.Switch9Label = uilabel(app.UIFigure);
            app.Switch9Label.Text = 'Switch9';
            app.Switch9 = uiswitch(app.UIFigure, 'toggle');
            app.Switch10Label = uilabel(app.UIFigure);
            app.Switch10Label.Text = 'Switch10';
            app.Switch10 = uiswitch(app.UIFigure, 'toggle');
            app.Efectoscanal1Label = uilabel(app.UIFigure);
            app.Efectoscanal1Label.Text = 'Efectos canal 1';
            app.Efectoscanal2Label = uilabel(app.UIFigure);
            app.Efectoscanal2Label.Text = {'Efectos canal 2'; ''};
            app.EfectosdeSonidoLabel = uilabel(app.UIFigure);
            app.EfectosdeSonidoLabel.Text = {'Efectos de Sonido'; ''};
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = musica

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end